classdef main_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ConsoleLogTab                 matlab.ui.container.Tab
        RefreshlogButton              matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        DisplaydataTab                matlab.ui.container.Tab
        SelectplotDropDown            matlab.ui.control.DropDown
        SelectplotDropDownLabel       matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        AcquisitionSettingsPanel      matlab.ui.container.Panel
        SwitchdelaysEditField         matlab.ui.control.NumericEditField
        SwitchdelaysEditFieldLabel    matlab.ui.control.Label
        nskipsVsEditField             matlab.ui.control.EditField
        nskipsVsEditFieldLabel        matlab.ui.control.Label
        VpickupEditField_2            matlab.ui.control.EditField
        nskipsvvLabel                 matlab.ui.control.Label
        VpickupEditField              matlab.ui.control.EditField
        VpickupEditFieldLabel         matlab.ui.control.Label
        VsEditField_2                 matlab.ui.control.EditField
        VsLabel                       matlab.ui.control.Label
        ListvoltagesourcesinkandpickupchannelsLabel  matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        Label                         matlab.ui.control.Label
        RESETButton                   matlab.ui.control.Button
        STARTACQUISITIONButton        matlab.ui.control.Button
        DataSettingsPanel             matlab.ui.container.Panel
        optionsDropDown               matlab.ui.control.DropDown
        optionsDropDownLabel          matlab.ui.control.Label
        LoadexistingdataCheckBox      matlab.ui.control.CheckBox
        SavedataCheckBox              matlab.ui.control.CheckBox
        FileEditField                 matlab.ui.control.EditField
        FileLabel                     matlab.ui.control.Label
        PathEditField                 matlab.ui.control.EditField
        PathEditFieldLabel            matlab.ui.control.Label
        FrequencySettingsPanel        matlab.ui.container.Panel
        Tone2FrequenciesEditField     matlab.ui.control.EditField
        Tone2FrequenciesLabel         matlab.ui.control.Label
        Tone1FrequenciesEditField     matlab.ui.control.EditField
        Tone1FrequenciesLabel         matlab.ui.control.Label
        EnabledualtoneacquisitionCheckBox  matlab.ui.control.CheckBox
        EnablefrequencysweepCheckBox  matlab.ui.control.CheckBox
        DatasetsEditField             matlab.ui.control.NumericEditField
        DatasetsLabel                 matlab.ui.control.Label
        VsEditField                   matlab.ui.control.EditField
        VsEditFieldLabel              matlab.ui.control.Label
        AveragesEditField             matlab.ui.control.NumericEditField
        AveragesLabel                 matlab.ui.control.Label
        UserOptionsPanel              matlab.ui.container.Panel
        DebugModesPanel               matlab.ui.container.Panel
        TurnonADCtestpatternCheckBox  matlab.ui.control.CheckBox
        EnabledigitalonlydemodDDStoFFTnoanalogCheckBox  matlab.ui.control.CheckBox
        SendADCsamplesandFFTspectrumdataDropDown  matlab.ui.control.DropDown
        Debugmode2sendrawADCandfullFFTspectrumdataDropDownLabel  matlab.ui.control.Label
        VppCalculationsDropDown       matlab.ui.control.DropDown
        VppCalculationsLabel          matlab.ui.control.Label
        THDCalculationsDropDown       matlab.ui.control.DropDown
        THDCalculationsLabel          matlab.ui.control.Label
        LeaveDAQoutputonafteracquisitionCheckBox  matlab.ui.control.CheckBox
        DisablesystemresetbeforeacquisitionCheckBox  matlab.ui.control.CheckBox
    end

    %----------------------------------------------------------------------
    % Parameters/variables
    %----------------------------------------------------------------------
    properties (Access = public)
        ui_params   % Structure with inputs to main function (called when start button is pressed)
    end

    properties (Access = private)
        logfile     % File name for log
        sfile_loc   % location of saved extracted data
        debug_mode  % true for debug mode
        nskips      % nskips for constructing iivv patterns
        run_main    % true to run main function
    end
    %----------------------------------------------------------------------
    % Functions
    %----------------------------------------------------------------------
    methods (Access = private)
        %------------------------------------------------------------------
        % Set up for logging -- adds timestamp to end of filename
        %------------------------------------------------------------------
        function fname = get_logfile(app)
            % Filenames and folders
            sfolder = app.ui_params(1).sfolder;         % save to this folder
            if app.ui_params(1).save_data && ~app.debug_mode
                fname = [sfolder 'log_' datestr(now,'HHMMSS'),'.txt'];
            else
                fname = [sfolder 'log.txt'];
            end
        end

        %-----------------------------------------------------------------
        % Console - update console with log
        %-----------------------------------------------------------------
        function display_log_to_panel(app)
            tmplog = fileread(app.logfile); % read log file
            app.TextArea.Value = tmplog;    % display to text area
            figure(app.UIFigure);
        end

        %-----------------------------------------------------------------
        % Display - select plot to show on panel
        %-----------------------------------------------------------------
        function select_plot_dropdown(app)
            value = app.SelectplotDropDown.Value;
            if ~isempty(app.sfile_loc) && isfile(app.sfile_loc) % if file exists, load data
                
                if app.ui_params(1).enable_adc_out > 0
                    value = 8;      % Plot the raw data (acquired samples and fft spectrum)
                elseif value == 8
                    value = 1;      % Raw data plot only works if enable_adc_out == 1
                end
                app.SelectplotDropDown.Value = value;   % update GUI
                switch value
                    case 1 % plot voltage
                        load(app.sfile_loc,'iis','vvs','adc_data_struct');
                        t = main_gui_plot_voltage(app.Panel,iis,vvs,adc_data_struct); 
                        % if app.ui_params(1).enable_freqsweep == 1
                        %     t = main_gui_plot_freqsweep(app.Panel,adc_data_struct); 
                        % else
                        %     t = main_gui_plot_voltage(app.Panel,iis,vvs,adc_data_struct);       
                        % end
                    case 2 % plot FFT
                        load(app.sfile_loc,'iis','vvs','data_iivv');
                        t = main_gui_plot_fft(app.Panel,iis,vvs,data_iivv);
                    case 3 % plot vmeas, isense, impedance data
                        load(app.sfile_loc,'eit_data');
                        t = main_gui_plot_eit(app.Panel,eit_data);
                    case 4 % plot electrode voltages
                        load(app.sfile_loc,'eit_data');
                        t = main_gui_plot_electrodes(app.Panel,eit_data);
                    case 5 % plot freq sweep
                        load(app.sfile_loc,'adc_data_struct');
                        t = main_gui_plot_freqsweep(app.Panel,adc_data_struct);
                    case 6 % plot rxz vs electrode number
                        load(app.sfile_loc,'eit_data');
                        if app.ui_params(1).enable_freqsweep == 1
                            t = main_gui_plot_rxz_freqsweep(app.Panel,eit_data); 
                        else
                            t = main_gui_plot_rxz(app.Panel,eit_data);        
                        end
                    case 7 % plot snr 
                        load(app.sfile_loc,'iis','vvs','adc_data_struct','plt_var_struct');
                        % t = main_gui_plot_elec_snr(app.Panel,iis,vvs,adc_data_struct);
                        t = main_gui_plot_elec_snr(app.Panel,iis,vvs,plt_var_struct);  
                    case 8 % plot debug data
                        load(app.sfile_loc,'iis','vvs','data_iivv');
                        t = main_gui_plot_raw_data(app.Panel,iis,vvs,data_iivv);
                    % case 7 % plot freqsweep
                    otherwise
                        t = ['Invalid plot selection. Value = ' num2str(value)];
                end

            else
                t = ['No data to plot. File not found: ' app.sfile_loc];
                console_text = 'Start acquisition to display data. No file to load.';
                app.TextArea.Value = console_text;    % display to text area
            end
            diary(app.logfile)
            disp(t);
            display_log_to_panel(app)
            figure(app.UIFigure);        % refresh app 
        end

       
        %-----------------------------------------------------------------
        % Get file names and data acquisition settings
        %-----------------------------------------------------------------
        function set_data_options(app)
            
            % get file names
            if app.debug_mode   % app.LoadexistingdataCheckBox.Value;
                [uifile,uipath] = uigetfile([app.ui_params(1).sfolder '*.mat']);
                app.ui_params(1).sfolder = uipath;
                app.ui_params(1).sfile_pfx = uifile;
            else
                app.ui_params(1).sfolder = app.PathEditField.Value;
                app.ui_params(1).sfile_pfx = app.FileEditField.Value;
            end

            % Make sure format is correct on file names
            if ~contains(app.ui_params(1).sfile_pfx,'.mat')
                app.ui_params(1).sfile_pfx = [app.ui_params(1).sfile_pfx '.mat'];
            end

            update_gui_filename_field(app); % update gui with filename

            % get parse/extraction settings
            app.run_main = 1;   % default is to run main function
            value = app.optionsDropDown.Value;
            switch value
                case 1 % default data acquisition
                    app.ui_params(1).parse_data = 1;
                    app.ui_params(1).extract_data = 1;
                case 2 % re-parse and re-extract
                    app.ui_params(1).parse_data = 1;
                    app.ui_params(1).extract_data = 1;
                case 3 % re-extract
                    app.ui_params(1).parse_data = 0;    % no parsing
                    app.ui_params(1).extract_data = 1;
                case 4
                    app.ui_params(1).parse_data = 0;    % no parsing
                    app.ui_params(1).extract_data = 0;  % no extraction
                    app.run_main = 0;   % load data, don't run main function
                otherwise
                    app.ui_params(1).parse_data = 1;
                    app.ui_params(1).extract_data = 1;
                    app.optionsDropDown.Value = 1;
            end
        end

        %-----------------------------------------------------------------
        % Update filename field on GUI (if path/file has changed)
        %-----------------------------------------------------------------
        function update_gui_filename_field(app)
            app.FileEditField.Value = app.ui_params(1).sfile_pfx;
            app.PathEditField.Value = app.ui_params(1).sfolder;
        end
    end

    %----------------------------------------------------------------------
    % Callbacks (more functions)
    %----------------------------------------------------------------------

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ui_params(1).nsets = app.DatasetsEditField.Value;    % SET NUMBER OF DATASETS TO ACQUIRE
            app.ui_params(1).navgs = app.AveragesEditField.Value;   % SET NUMBER OF AVERAGES TO COMPUTE
            app.ui_params(1).ii_filename = ''; % set to empty string 
            eval(['app.ui_params(1).vsource = [' app.VsEditField.Value '];']);
            eval(['app.ui_params(1).vsink = [' app.VsEditField_2.Value '];']);
            eval(['app.ui_params(1).vpickup = [' app.VpickupEditField.Value '];']);
            eval(['app.nskips = [' app.VpickupEditField_2.Value '];']);
            eval(['app.ui_params(1).nskips_ii = [' app.nskipsVsEditField.Value '];']);
            app.ui_params(1).enable_freqsweep = app.EnablefrequencysweepCheckBox.Value;
            app.ui_params(1).use_dual = app.EnabledualtoneacquisitionCheckBox.Value;
            eval(['app.ui_params(1).freq1_inds = [' app.Tone1FrequenciesEditField.Value '];']);
            app.ui_params(1).freq2_inds = str2double(app.Tone2FrequenciesEditField.Value);
            app.ui_params(1).save_data = app.SavedataCheckBox.Value;
            app.ui_params(1).sfolder = app.PathEditField.Value;
            app.ui_params(1).sfile_pfx = app.FileEditField.Value;
            app.ui_params(1).use_full_fft = str2double(app.THDCalculationsDropDown.Value);
            app.ui_params(1).use_fsig_bins = str2double(app.VppCalculationsDropDown.Value);
            app.ui_params(1).reset = 1;
            app.ui_params(1).disable_after_run = 1;
            app.ui_params(1).adc_testpattern_on = app.TurnonADCtestpatternCheckBox.Value;
            app.ui_params(1).enable_dds_to_fft = app.EnabledigitalonlydemodDDStoFFTnoanalogCheckBox.Value;
            app.ui_params(1).enable_adc_out = str2double(app.SendADCsamplesandFFTspectrumdataDropDown.Value);
            app.ui_params(1).save_log = 1; % always save
            set_data_options(app);
            app.debug_mode = app.LoadexistingdataCheckBox.Value;
            app.ui_params(1).mux_delay = app.SwitchdelaysEditField.Value;
            app.logfile = 'log.txt';
            app.sfile_loc = '';
            app.Lamp.Color = [0.502 0.502 0.502];
            app.TextArea.Value = 'Ready to start data acquisition.';    % display to text area
            addpath mfiles
        end

        % Button pushed function: STARTACQUISITIONButton
        function cb_start_acquisition(app, event)
            app.Lamp.Color = 'green';             % START ACQUISITION
            % app.Lamp.Enable = 'on';
            set_data_options(app)
            app.logfile = get_logfile(app);
            app.ui_params(1).logfile = app.logfile;
            app.sfile_loc = [app.ui_params(1).sfolder app.ui_params(1).sfile_pfx];
            %--------------------------------------------------------------
            if app.run_main % update file name and ui params if applicable
                [app.sfile_loc,app.ui_params] = main_gui_functions(app.ui_params,app.debug_mode);
            end
            %--------------------------------------------------------------
            % Update file name and path fields on GUI
            update_gui_filename_field(app);
            select_plot_dropdown(app);
            display_log_to_panel(app)

        end

        % Value changed function: DatasetsEditField
        function cb_set_ndatasets(app, event)
            app.ui_params(1).nsets = app.DatasetsEditField.Value;    % SET NUMBER OF DATASETS TO ACQUIRE
        end

        % Value changed function: AveragesEditField
        function cb_set_naverages(app, event)
            app.ui_params(1).navgs = app.AveragesEditField.Value;   % SET NUMBER OF AVERAGES TO COMPUTE

        end

        % Value changed function: EnablefrequencysweepCheckBox
        function cb_enable_freqsweep(app, event)
            value = app.EnablefrequencysweepCheckBox.Value;
            app.ui_params(1).enable_freqsweep = value;
            if value
                app.Tone1FrequenciesEditField.BackgroundColor = [0.90,0.90,0.90]; % grey-out field
            else
                app.Tone1FrequenciesEditField.BackgroundColor = 'white';
            end
        end

        % Value changed function: EnabledualtoneacquisitionCheckBox
        function cb_enable_dual_dds(app, event)
            value = app.EnabledualtoneacquisitionCheckBox.Value;
            app.ui_params(1).use_dual = value;
            if value
                app.Tone2FrequenciesEditField.BackgroundColor = 'white';
            else
                app.Tone2FrequenciesEditField.BackgroundColor = [0.90,0.90,0.90]; % grey-out field
            end
        end

        % Value changed function: Tone1FrequenciesEditField
        function cb_set_dds_f1(app, event)
            value = app.Tone1FrequenciesEditField.Value;
            eval(['app.ui_params(1).freq1_inds = [' value '];']);

        end

        % Value changed function: Tone2FrequenciesEditField
        function cb_set_dds_f2(app, event)
            app.ui_params(1).freq2_inds = str2double(app.Tone2FrequenciesEditField.Value);

        end

        % Value changed function: SavedataCheckBox
        function cb_set_save_data(app, event)
            value = app.SavedataCheckBox.Value;
            app.ui_params(1).save_data = value;
            if value
                app.FileEditField.BackgroundColor = 'white';
                app.PathEditField.BackgroundColor = 'white';
            else
                app.FileEditField.BackgroundColor = [0.90,0.90,0.90]; % grey-out field
                app.PathEditField.BackgroundColor = [0.90,0.90,0.90];
            end
        end

        % Value changed function: PathEditField
        function cb_set_save_folder(app, event)
            app.ui_params(1).sfolder = app.PathEditField.Value;

        end

        % Value changed function: FileEditField
        function cb_set_save_filename(app, event)
            app.ui_params(1).sfile_pfx = app.FileEditField.Value;

        end

        % Value changed function: THDCalculationsDropDown
        function cb_set_use_full_spectrum(app, event)

            app.ui_params(1).use_full_fft = str2double(app.THDCalculationsDropDown.Value);
        end

        % Value changed function: VppCalculationsDropDown
        function cb_set_use_fsig_bins(app, event)

            app.ui_params(1).use_fsig_bins = str2double(app.VppCalculationsDropDown.Value);
        end

        % Value changed function: 
        % DisablesystemresetbeforeacquisitionCheckBox
        function cb_disable_reset(app, event)
            disable_reset = app.DisablesystemresetbeforeacquisitionCheckBox.Value;
            app.ui_params(1).reset = ~disable_reset;
        end

        % Value changed function: LeaveDAQoutputonafteracquisitionCheckBox
        function cb_keep_output_enabled(app, event)
            keep_output_on_after_run = app.LeaveDAQoutputonafteracquisitionCheckBox.Value;
            app.ui_params(1).disable_after_run = ~keep_output_on_after_run;
        end

        % Value changed function: TurnonADCtestpatternCheckBox
        function cb_set_adc_testpat(app, event)
            app.ui_params(1).adc_testpattern_on = app.TurnonADCtestpatternCheckBox.Value;
        end

        % Value changed function: 
        % EnabledigitalonlydemodDDStoFFTnoanalogCheckBox
        function cb_enable_debug_mode_dds_to_fft(app, event)
            app.ui_params(1).enable_dds_to_fft = app.EnabledigitalonlydemodDDStoFFTnoanalogCheckBox.Value;
        end

        % Value changed function: SendADCsamplesandFFTspectrumdataDropDown
        function cb_enable_debug_mode_adc_output(app, event)
            app.ui_params(1).enable_adc_out = str2double(app.SendADCsamplesandFFTspectrumdataDropDown.Value);
        end

        % Button pushed function: RefreshlogButton
        function cb_display_logfile(app, event)
            display_log_to_panel(app)
        end

        % Button pushed function: RESETButton
        function cb_reset_gui(app, event)
            startupFcn(app)
        end

        % Value changed function: LoadexistingdataCheckBox
        function cb_set_debug_mode(app, event)
            value = app.LoadexistingdataCheckBox.Value;
            app.debug_mode = value;
            if value
                app.optionsDropDown.BackgroundColor = 'white';
                if app.optionsDropDown.Value == 1
                    app.optionsDropDown.Value = 4;
                end
            else
                app.optionsDropDown.BackgroundColor = [0.90,0.90,0.90]; % grey-out field
                app.optionsDropDown.Value = 1;
            end
        end

        % Value changed function: SelectplotDropDown
        function cb_select_plot(app, event)

            select_plot_dropdown(app);
        end

        % Value changed function: VpickupEditField_2
        function cb_set_nskips(app, event)
            value = app.VpickupEditField_2.Value;
            eval(['app.nskips = [' value '];']);
        end

        % Value changed function: VsEditField
        function cb_set_vsource(app, event)
            value = app.VsEditField.Value;
            eval(['app.ui_params(1).vsource = [' value '];']);
        end

        % Value changed function: VsEditField_2
        function cb_set_vsink(app, event)
            value = app.VsEditField_2.Value;
            eval(['app.ui_params(1).vsink = [' value '];']);
        end

        % Value changed function: VpickupEditField
        function cb_set_vpickup(app, event)
            value = app.VpickupEditField.Value;
            eval(['app.ui_params(1).vpickup = [' value '];']);
        end

        % Value changed function: nskipsVsEditField
        function cb_set_nskips_ii(app, event)
            value = app.nskipsVsEditField.Value;
            eval(['app.ui_params(1).nskips_ii = [' value '];']);
        end

        % Callback function
        function cb_set_data_options(app, event)
            set_data_options(app);

        end

        % Value changed function: SwitchdelaysEditField
        function cb_set_mux_delay(app, event)
      
            app.ui_params(1).mux_delay = app.SwitchdelaysEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UserOptionsPanel = uipanel(app.UIFigure);
            app.UserOptionsPanel.Title = 'User Options';
            app.DisablesystemresetbeforeacquisitionCheckBox = uicheckbox(app.UserOptionsPanel);
            app.DisablesystemresetbeforeacquisitionCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_disable_reset, true);
            app.DisablesystemresetbeforeacquisitionCheckBox.Text = 'Disable system reset before acquisition';
            app.LeaveDAQoutputonafteracquisitionCheckBox = uicheckbox(app.UserOptionsPanel);
            app.LeaveDAQoutputonafteracquisitionCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_keep_output_enabled, true);
            app.LeaveDAQoutputonafteracquisitionCheckBox.Text = 'Leave DAQ output on after acquisition';
            app.THDCalculationsLabel = uilabel(app.UserOptionsPanel);
            app.THDCalculationsLabel.Text = 'THD Calculations:';
            app.THDCalculationsDropDown = uidropdown(app.UserOptionsPanel);
            app.THDCalculationsDropDown.Items = {'Use full FFT spectrum (bins 1-N)', 'Use single-sided FFT (bins 1-N/2)'};
            app.THDCalculationsDropDown.ItemsData = {'1', '0'};
            app.THDCalculationsDropDown.ValueChangedFcn = createCallbackFcn(app, @cb_set_use_full_spectrum, true);
            app.THDCalculationsDropDown.Value = '1';
            app.VppCalculationsLabel = uilabel(app.UserOptionsPanel);
            app.VppCalculationsLabel.Text = 'Vpp Calculations:';
            app.VppCalculationsDropDown = uidropdown(app.UserOptionsPanel);
            app.VppCalculationsDropDown.Items = {'Use full FFT (bins k and N-k)', 'Use single-sided FFT (bin k)', 'Use single-sided FFT (bin N-k)'};
            app.VppCalculationsDropDown.ItemsData = {'3', '1', '2'};
            app.VppCalculationsDropDown.ValueChangedFcn = createCallbackFcn(app, @cb_set_use_fsig_bins, true);
            app.VppCalculationsDropDown.Value = '3';
            app.DebugModesPanel = uipanel(app.UserOptionsPanel);
            app.DebugModesPanel.Title = 'Debug Modes';
            app.Debugmode2sendrawADCandfullFFTspectrumdataDropDownLabel = uilabel(app.DebugModesPanel);
            app.Debugmode2sendrawADCandfullFFTspectrumdataDropDownLabel.Text = 'Send ADC samples and FFT spectrum data:';
            app.SendADCsamplesandFFTspectrumdataDropDown = uidropdown(app.DebugModesPanel);
            app.SendADCsamplesandFFTspectrumdataDropDown.Items = {'(select ADC)', 'Isense ', 'Vpickup Ch 1', 'Vpickup Ch 2'};
            app.SendADCsamplesandFFTspectrumdataDropDown.ItemsData = {'0', '1', '2', '3'};
            app.SendADCsamplesandFFTspectrumdataDropDown.ValueChangedFcn = createCallbackFcn(app, @cb_enable_debug_mode_adc_output, true);
            app.SendADCsamplesandFFTspectrumdataDropDown.Value = '0';
            app.EnabledigitalonlydemodDDStoFFTnoanalogCheckBox = uicheckbox(app.DebugModesPanel);
            app.EnabledigitalonlydemodDDStoFFTnoanalogCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_enable_debug_mode_dds_to_fft, true);
            app.EnabledigitalonlydemodDDStoFFTnoanalogCheckBox.Text = 'Enable digital-only demod: DDS to FFT (no analog)';
            app.TurnonADCtestpatternCheckBox = uicheckbox(app.DebugModesPanel);
            app.TurnonADCtestpatternCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_set_adc_testpat, true);
            app.TurnonADCtestpatternCheckBox.Text = 'Turn on ADC testpattern';
            app.AcquisitionSettingsPanel = uipanel(app.UIFigure);
            app.AcquisitionSettingsPanel.Title = 'Acquisition Settings';
            app.AveragesLabel = uilabel(app.AcquisitionSettingsPanel);
            app.AveragesLabel.Text = '# Averages:';
            app.AveragesEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.AveragesEditField.Limits = [1 128];
            app.AveragesEditField.ValueDisplayFormat = '%.0f';
            app.AveragesEditField.ValueChangedFcn = createCallbackFcn(app, @cb_set_naverages, true);
            app.AveragesEditField.Value = 4;
            app.VsEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.VsEditFieldLabel.Text = 'Vs+';
            app.VsEditField = uieditfield(app.AcquisitionSettingsPanel, 'text');
            app.VsEditField.ValueChangedFcn = createCallbackFcn(app, @cb_set_vsource, true);
            app.VsEditField.Value = '1:16';
            app.DatasetsLabel = uilabel(app.AcquisitionSettingsPanel);
            app.DatasetsLabel.Text = '# Datasets:';
            app.DatasetsEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.DatasetsEditField.Limits = [1 100];
            app.DatasetsEditField.ValueDisplayFormat = '%.0f';
            app.DatasetsEditField.ValueChangedFcn = createCallbackFcn(app, @cb_set_ndatasets, true);
            app.DatasetsEditField.Value = 1;
            app.FrequencySettingsPanel = uipanel(app.AcquisitionSettingsPanel);
            app.FrequencySettingsPanel.Title = 'Frequency Settings';
            app.EnablefrequencysweepCheckBox = uicheckbox(app.FrequencySettingsPanel);
            app.EnablefrequencysweepCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_enable_freqsweep, true);
            app.EnablefrequencysweepCheckBox.Text = 'Enable frequency sweep';
            app.EnabledualtoneacquisitionCheckBox = uicheckbox(app.FrequencySettingsPanel);
            app.EnabledualtoneacquisitionCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_enable_dual_dds, true);
            app.EnabledualtoneacquisitionCheckBox.Text = 'Enable dual-tone acquisition';
            app.Tone1FrequenciesLabel = uilabel(app.FrequencySettingsPanel);
            app.Tone1FrequenciesLabel.Text = 'Tone 1 Frequencies:';
            app.Tone1FrequenciesEditField = uieditfield(app.FrequencySettingsPanel, 'text');
            app.Tone1FrequenciesEditField.CharacterLimits = [1 64];
            app.Tone1FrequenciesEditField.ValueChangedFcn = createCallbackFcn(app, @cb_set_dds_f1, true);
            app.Tone1FrequenciesEditField.Value = '40';
            app.Tone2FrequenciesLabel = uilabel(app.FrequencySettingsPanel);
            app.Tone2FrequenciesLabel.Text = 'Tone 2 Frequencies:';
            app.Tone2FrequenciesEditField = uieditfield(app.FrequencySettingsPanel, 'text');
            app.Tone2FrequenciesEditField.CharacterLimits = [0 64];
            app.Tone2FrequenciesEditField.InputType = 'digits';
            app.Tone2FrequenciesEditField.ValueChangedFcn = createCallbackFcn(app, @cb_set_dds_f2, true);
            app.DataSettingsPanel = uipanel(app.AcquisitionSettingsPanel);
            app.DataSettingsPanel.Title = 'Data Settings';
            app.PathEditFieldLabel = uilabel(app.DataSettingsPanel);
            app.PathEditFieldLabel.Text = 'Path:';
            app.PathEditField = uieditfield(app.DataSettingsPanel, 'text');
            app.PathEditField.ValueChangedFcn = createCallbackFcn(app, @cb_set_save_folder, true);
            app.PathEditField.Value = 'mat/data_folder_1/';
            app.FileLabel = uilabel(app.DataSettingsPanel);
            app.FileLabel.Text = 'File:';
            app.FileEditField = uieditfield(app.DataSettingsPanel, 'text');
            app.FileEditField.ValueChangedFcn = createCallbackFcn(app, @cb_set_save_filename, true);
            app.FileEditField.Placeholder = 'dataset_1';
            app.FileEditField.Value = 'dataset_1';
            app.SavedataCheckBox = uicheckbox(app.DataSettingsPanel);
            app.SavedataCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_set_save_data, true);
            app.SavedataCheckBox.Text = 'Save data';
            app.SavedataCheckBox.Value = true;
            app.LoadexistingdataCheckBox = uicheckbox(app.DataSettingsPanel);
            app.LoadexistingdataCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_set_debug_mode, true);
            app.LoadexistingdataCheckBox.Text = 'Load existing data';
            app.optionsDropDownLabel = uilabel(app.DataSettingsPanel);
            app.optionsDropDownLabel.Text = 'options';
            app.optionsDropDown = uidropdown(app.DataSettingsPanel);
            app.optionsDropDown.Items = {'Run data acquisition defaults', 'Re-parse serial data, extract voltages', 'Re-extract voltages from parsed data', 'Load extracted data'};
            app.optionsDropDown.ItemsData = [1 2 3 4];
            app.optionsDropDown.Value = 1;
            app.STARTACQUISITIONButton = uibutton(app.AcquisitionSettingsPanel, 'push');
            app.STARTACQUISITIONButton.ButtonPushedFcn = createCallbackFcn(app, @cb_start_acquisition, true);
            app.STARTACQUISITIONButton.Text = 'START ACQUISITION';
            app.RESETButton = uibutton(app.AcquisitionSettingsPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @cb_reset_gui, true);
            app.RESETButton.Text = 'RESET';
            app.Label = uilabel(app.AcquisitionSettingsPanel);
            app.Label.Text = '';
            app.Lamp = uilamp(app.AcquisitionSettingsPanel);
            app.Lamp.Tag = 'running_acquisition_lamp';
            app.ListvoltagesourcesinkandpickupchannelsLabel = uilabel(app.AcquisitionSettingsPanel);
            app.ListvoltagesourcesinkandpickupchannelsLabel.Text = 'List voltage source, sink, and pickup channels:';
            app.VsLabel = uilabel(app.AcquisitionSettingsPanel);
            app.VsLabel.Text = 'Vs-';
            app.VsEditField_2 = uieditfield(app.AcquisitionSettingsPanel, 'text');
            app.VsEditField_2.ValueChangedFcn = createCallbackFcn(app, @cb_set_vsink, true);
            app.VsEditField_2.Value = '1:16';
            app.VpickupEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.VpickupEditFieldLabel.Text = 'Vpickup';
            app.VpickupEditField = uieditfield(app.AcquisitionSettingsPanel, 'text');
            app.VpickupEditField.ValueChangedFcn = createCallbackFcn(app, @cb_set_vpickup, true);
            app.VpickupEditField.Value = '1:16';
            app.nskipsvvLabel = uilabel(app.AcquisitionSettingsPanel);
            app.nskipsvvLabel.Text = 'Vpickup';
            app.VpickupEditField_2 = uieditfield(app.AcquisitionSettingsPanel, 'text');
            app.VpickupEditField_2.ValueChangedFcn = createCallbackFcn(app, @cb_set_nskips, true);
            app.VpickupEditField_2.Value = '0,2,4';
            app.nskipsVsEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.nskipsVsEditFieldLabel.Text = 'nskips: Vs+/-';
            app.nskipsVsEditField = uieditfield(app.AcquisitionSettingsPanel, 'text');
            app.nskipsVsEditField.ValueChangedFcn = createCallbackFcn(app, @cb_set_nskips_ii, true);
            app.nskipsVsEditField.Value = '0';
            app.SwitchdelaysEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.SwitchdelaysEditFieldLabel.Text = 'Switch delay (s)';
            app.SwitchdelaysEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.SwitchdelaysEditField.Limits = [0 4];
            app.SwitchdelaysEditField.ValueChangedFcn = createCallbackFcn(app, @cb_set_mux_delay, true);
            app.SwitchdelaysEditField.Value = 0.1;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConsoleLogTab = uitab(app.TabGroup);
            app.ConsoleLogTab.Title = 'Console (Log)';
            app.TextArea = uitextarea(app.ConsoleLogTab);
            app.RefreshlogButton = uibutton(app.ConsoleLogTab, 'push');
            app.RefreshlogButton.ButtonPushedFcn = createCallbackFcn(app, @cb_display_logfile, true);
            app.RefreshlogButton.Text = 'Refresh log';
            app.DisplaydataTab = uitab(app.TabGroup);
            app.DisplaydataTab.Title = 'Display data';
            app.Panel = uipanel(app.DisplaydataTab);
            app.Panel.Tag = 'voltage_plot_panel';
            app.SelectplotDropDownLabel = uilabel(app.DisplaydataTab);
            app.SelectplotDropDownLabel.Text = 'Select plot';
            app.SelectplotDropDown = uidropdown(app.DisplaydataTab);
            app.SelectplotDropDown.Items = {'Voltage per ii pair', 'FFT data', 'Sensed EIT data (Vload, Isense, Z)', 'Voltage per electrode', 'Frequency sweep', 'Impedance data (RX vs electrode)', 'SNR per ii pair', 'Raw ADC and FFT spectrum data'};
            app.SelectplotDropDown.ItemsData = [1 2 3 4 5 6 7 8];
            app.SelectplotDropDown.ValueChangedFcn = createCallbackFcn(app, @cb_select_plot, true);
            app.SelectplotDropDown.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end